classdef RealLifeComicDesignGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        LoadImageButton                matlab.ui.control.Button
        SaveCurrentSettingsButton      matlab.ui.control.Button
        PreviewSavedSettingsButton     matlab.ui.control.Button
        SaveImageButton                matlab.ui.control.Button
        QuantizationPanel              matlab.ui.container.Panel
        NumberofGrayShadesSliderLabel  matlab.ui.control.Label
        NumberofGrayShadesSlider       matlab.ui.control.Slider
        NoiseLevelFillSliderLabel      matlab.ui.control.Label
        NoiseLevelFillSlider           matlab.ui.control.Slider
        NoiseReductionFilterFillDropDownLabel  matlab.ui.control.Label
        NoiseReductionFilterFillDropDown  matlab.ui.control.DropDown
        EdgeDetectionPanel             matlab.ui.container.Panel
        NoiseLevelEdgeSliderLabel      matlab.ui.control.Label
        NoiseLevelEdgeSlider           matlab.ui.control.Slider
        EdgeDetectionMethodDropDownLabel  matlab.ui.control.Label
        EdgeDetectionMethodDropDown    matlab.ui.control.DropDown
        ThresholdSliderLabel           matlab.ui.control.Label
        ThresholdSlider                matlab.ui.control.Slider
        NoiseReductionFilterEdgeDropDownLabel  matlab.ui.control.Label
        NoiseReductionFilterEdgeDropDown  matlab.ui.control.DropDown
        EdgeModificationPanel          matlab.ui.container.Panel
        BoldenEdgesSliderLabel         matlab.ui.control.Label
        BoldenEdgesSlider              matlab.ui.control.Slider
        ConstrastPanel                 matlab.ui.container.Panel
        LowerBoundSliderLabel          matlab.ui.control.Label
        LowerBoundSlider               matlab.ui.control.Slider
        UpperBoundSliderLabel          matlab.ui.control.Label
        UpperBoundSlider               matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        ImageName;

        % Function arguments
        GrayScaleImage;
        % Contrast
        Hist_StretchLower = 0;
        Hist_StretchUpper = 1;
        % Edge Detection
        Edge_NoiseReduceFilter = 0;
        Edge_NoiseLevel = 1;
        Edge_DetectionMethod = 0;
        Edge_DetectionThreshold = 0;
        % Edge Modification
        ModE_DilationLevel = 0;
        % Quantization
        Fill_NoiseReduceFilter = 0;
        Fill_NoiseLevel = 1;
        Fill_NumOfShades = 50;
        
        SettingsSave = false;
        
        Save_Hist_StretchLower = 0;
        Save_Hist_StretchUpper = 1;
        % Edge Detection
        Save_Edge_NoiseReduceFilter = 0;
        Save_Edge_NoiseLevel = 1;
        Save_Edge_DetectionMethod = 0;
        Save_Edge_DetectionThreshold = 0;
        % Edge Modification
        Save_ModE_DilationLevel = 0;
        % Quantization
        Save_Fill_NoiseReduceFilter = 0;
        Save_Fill_NoiseLevel = 1;
        Save_Fill_NumOfShades = 50;
        
        SavedImageSource;
        PreviewApp;
    end


    methods (Access = private)
        
        function DisplayImage(app)
            try
            % Process and display image          
            app.Image.ImageSource = cartoonifyFilter(app.GrayScaleImage, app.Hist_StretchLower, app.Hist_StretchUpper, app.Edge_NoiseReduceFilter,...
                app.Edge_NoiseLevel, app.Edge_DetectionMethod, app.Edge_DetectionThreshold, app.ModE_DilationLevel, app.Fill_NoiseReduceFilter,...
                app.Fill_NoiseLevel, app.Fill_NumOfShades);
            catch
                % Do Nothing
            end
        end
        
        function resultSavedImage = SavedImage(app)
            try
            % Process and display image          
            resultSavedImage = cartoonifyFilter(app.GrayScaleImage, app.Save_Hist_StretchLower, app.Save_Hist_StretchUpper, app.Save_Edge_NoiseReduceFilter,...
                app.Save_Edge_NoiseLevel, app.Save_Edge_DetectionMethod, app.Save_Edge_DetectionThreshold, app.Save_ModE_DilationLevel, app.Save_Fill_NoiseReduceFilter,...
                app.Save_Fill_NoiseLevel, app.Save_Fill_NumOfShades);
            catch
                % Do Nothing
            end
        end
    end
    
    methods (Access = public)
        
        function ApplySavedSettings(app)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Display saved image
                app.Image.ImageSource = app.SavedImage();
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Revert all parameters
                app.Hist_StretchLower = app.Save_Hist_StretchLower;
                app.Hist_StretchUpper = app.Save_Hist_StretchUpper;
                % Edge Detection
                app.Edge_NoiseReduceFilter = app.Save_Edge_NoiseReduceFilter;
                app.Edge_NoiseLevel = app.Save_Edge_NoiseLevel;
                app.Edge_DetectionMethod = app.Save_Edge_DetectionMethod;
                app.Edge_DetectionThreshold = app.Save_Edge_DetectionThreshold;
                % Edge Modification
                app.ModE_DilationLevel = app.Save_ModE_DilationLevel;
                % Quantization
                app.Fill_NoiseReduceFilter = app.Save_Fill_NoiseReduceFilter;
                app.Fill_NoiseLevel = app.Save_Fill_NoiseLevel;
                app.Fill_NumOfShades = app.Save_Fill_NumOfShades;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Reset sliders and dropdown menu to saved settings
                % Contrast Panel
                app.LowerBoundSlider.Value = app.Save_Hist_StretchLower;
                app.UpperBoundSlider.Value = app.Save_Hist_StretchUpper;
                
                % Edge Detection panel
                if app.Save_Edge_NoiseReduceFilter == 0
                    app.NoiseReductionFilterEdgeDropDown.Value = "None";
                elseif app.Save_Edge_NoiseReduceFilter == 1
                    app.NoiseReductionFilterEdgeDropDown.Value = "Average";
                else 
                    app.NoiseReductionFilterEdgeDropDown.Value = "Median";
                end
                
                app.NoiseLevelEdgeSlider.Value = app.Save_Edge_NoiseLevel;
                
                if app.Save_Edge_DetectionMethod == 0
                    app.EdgeDetectionMethodDropDown.Value = "None";
                elseif app.Save_Edge_DetectionMethod == 1
                    app.EdgeDetectionMethodDropDown.Value = "Laplacian";
                elseif app.Save_Edge_DetectionMethod == 2
                    app.EdgeDetectionMethodDropDown.Value = "LOG";
                else 
                    app.EdgeDetectionMethodDropDown.Value = "Canny";
                end
                
                app.ThresholdSlider.Value = app.Save_Edge_DetectionThreshold;
                
                % Edge Modification panel
                app.BoldenEdgesSlider.Value = app.Save_ModE_DilationLevel;
                
                % Quantization panel
                if app.Save_Fill_NoiseReduceFilter == 0
                    app.NoiseReductionFilterFillDropDown.Value = "None";
                elseif app.Save_Fill_NoiseReduceFilter == 1
                    app.NoiseReductionFilterFillDropDown.Value = "Average";
                else
                    app.NoiseReductionFilterFillDropDown.Value = "Median";
                end
                
                app.NoiseLevelFillSlider.Value = app.Save_Fill_NoiseLevel;
                
                app.NumberofGrayShadesSlider.Value = app.Save_Fill_NumOfShades;
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
        
        try
            [ImageFile, ImagePath] = uigetfile({'*.*';'*.png';'*.jpg'}, 'Select an Image (No .tif image)');    % Open File Explorer
            app.ImageName = fullfile(ImagePath, ImageFile);     % Contatenate file name and path
            ImageRead = imread(app.ImageName);
            [~,~,d] = size(ImageRead); 
            
            % Contrast
            app.Hist_StretchLower = 0;
            app.LowerBoundSlider.Value = 0;
            app.Hist_StretchUpper = 1;
            app.UpperBoundSlider.Value = 1;
            
            % Edge Modification
            app.ModE_DilationLevel = 0;
            app.BoldenEdgesSlider.Value = 0;
            
            % Quantization
            app.Fill_NumOfShades = 50;
            app.NumberofGrayShadesSlider.Value = 50;
            
            if d ~= 1
                app.GrayScaleImage = rgb2gray(ImageRead); % convert to grayscale
            else
                app.GrayScaleImage = ImageRead; %already grayscale
            end
            
            app.Image.ImageSource = cat(3, app.GrayScaleImage, app.GrayScaleImage, app.GrayScaleImage); % Convert the dimensions of the image to n x m x 3  
                                                                                                        % and display image
        catch
            % Do nothing
        end
        
        end

        % Value changing function: NoiseLevelEdgeSlider
        function NoiseLevelEdgeSliderValueChanging(app, event)
            app.Edge_NoiseLevel = round(event.Value);
            
            % Display Image Function
            app.DisplayImage();
        end

        % Value changing function: LowerBoundSlider
        function LowerBoundSliderValueChanging(app, event)
            app.Hist_StretchLower = event.Value;
           
            % Process and display image          
            app.DisplayImage();
        end

        % Value changing function: UpperBoundSlider
        function UpperBoundSliderValueChanging(app, event)
            app.Hist_StretchUpper = event.Value;
            
            % Process and display image
            app.DisplayImage();
            
        end

        % Value changed function: NoiseReductionFilterEdgeDropDown
        function NoiseReductionFilterEdgeDropDownValueChanged(app, event)
            value = app.NoiseReductionFilterEdgeDropDown.Value;

            % Selecting filter
            if value == "None"
                app.Edge_NoiseReduceFilter = 0;
                app.NoiseLevelEdgeSlider.Limits = [0 50];
            elseif value == "Average"
                app.Edge_NoiseReduceFilter = 1;
                app.Edge_NoiseLevel = 1;
                app.NoiseLevelEdgeSlider.Limits = [1 50];
            else 
                app.Edge_NoiseReduceFilter = 2;
                app.Edge_NoiseLevel = 1;
                app.NoiseLevelEdgeSlider.Limits = [1 50];
            end
            
            % Display Image Function
            app.DisplayImage();
        end

        % Value changed function: EdgeDetectionMethodDropDown
        function EdgeDetectionMethodDropDownValueChanged(app, event)
            value = app.EdgeDetectionMethodDropDown.Value;
            
            % Selecting filter
            if value == "None"
                app.Edge_DetectionMethod = 0;
            elseif value =="Laplacian"
                app.Edge_DetectionMethod = 1;
                app.Edge_DetectionThreshold = 0;
                app.ThresholdSlider.Limits = [0 1];
            elseif value == "LOG"
                app.Edge_DetectionMethod = 2;
                app.Edge_DetectionThreshold = 0;
                app.ThresholdSlider.Limits = [0 0.999];
            else 
                app.Edge_DetectionMethod = 3; 
                app.ThresholdSlider.Limits = [0 1];
            end
            
            % Display Image Function
            app.DisplayImage();
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            app.Edge_DetectionThreshold = event.Value;
            
            % Display Image Function
            app.DisplayImage();

        end

        % Value changing function: BoldenEdgesSlider
        function BoldenEdgesSliderValueChanging(app, event)
            app.ModE_DilationLevel = round(event.Value);
            
            % Display Image Function
            app.DisplayImage();
        end

        % Value changed function: NoiseReductionFilterFillDropDown
        function NoiseReductionFilterFillDropDownValueChanged(app, event)
            value = app.NoiseReductionFilterFillDropDown.Value;
            
            % Selecting filter
            if value == "None"
                app.Fill_NoiseReduceFilter = 0;
                app.NoiseLevelFillSlider.Limits = [0 50];
            elseif value == "Average"
                app.Fill_NoiseReduceFilter = 1;
                app.Fill_NoiseLevel = 1;
                app.NoiseLevelFillSlider.Limits = [1 50];
                
            else 
                app.Fill_NoiseReduceFilter = 2;
                app.Fill_NoiseLevel = 1;
                app.NoiseLevelFillSlider.Limits = [1 50];
                
            end
            
            % Display Image Function
            app.DisplayImage();
            
        end

        % Value changing function: NoiseLevelFillSlider
        function NoiseLevelFillSliderValueChanging(app, event)
            app.Fill_NoiseLevel = round(event.Value);
                        
            % Display Image Function
            app.DisplayImage();
            
        end

        % Value changing function: NumberofGrayShadesSlider
        function NumberofGrayShadesSliderValueChanging(app, event)
            app.Fill_NumOfShades = event.Value;
            
            % Display Image Function
            app.DisplayImage();
            
        end

        % Value changed function: LowerBoundSlider
        function LowerBoundSliderValueChanged(app, event)
            app.Hist_StretchLower = app.LowerBoundSlider.Value;
            
            if app.Hist_StretchLower > app.Hist_StretchUpper
                app.Hist_StretchUpper = app.Hist_StretchLower;
                app.UpperBoundSlider.Value = double(app.Hist_StretchUpper);                 
            end
            % Process and display image          
            app.DisplayImage();
        end

        % Value changed function: UpperBoundSlider
        function UpperBoundSliderValueChanged(app, event)
            app.Hist_StretchUpper = app.UpperBoundSlider.Value;
            
            if app.Hist_StretchUpper < app.Hist_StretchLower  
                app.Hist_StretchLower = app.Hist_StretchUpper;
                app.LowerBoundSlider.Value = double(app.Hist_StretchLower);
            end
            
            % Process and display image
            app.DisplayImage();
        end

        % Button pushed function: SaveCurrentSettingsButton
        function SaveCurrentSettingsButtonPushed(app, event)
        app.Save_Hist_StretchLower = app.Hist_StretchLower;
        app.Save_Hist_StretchUpper = app.Hist_StretchUpper;
        % Edge Detection
        app.Save_Edge_NoiseReduceFilter = app.Edge_NoiseReduceFilter;
        app.Save_Edge_NoiseLevel = app.Edge_NoiseLevel;
        app.Save_Edge_DetectionMethod = app.Edge_DetectionMethod;
        app.Save_Edge_DetectionThreshold = app.Edge_DetectionThreshold;
        % Edge Modification
        app.Save_ModE_DilationLevel = app.ModE_DilationLevel;
        % Quantization
        app.Save_Fill_NoiseReduceFilter = app.Fill_NoiseReduceFilter;
        app.Save_Fill_NoiseLevel = app.Fill_NoiseLevel;
        app.Save_Fill_NumOfShades = app.Fill_NumOfShades;
        
        app.SettingsSave = true;
        end

        % Button pushed function: PreviewSavedSettingsButton
        function PreviewSavedSettingsButtonPushed(app, event)
            % Settings have to be saved before previewing images
            try
                if app.SettingsSave == true
                    delete(app.PreviewApp) % Delete previous window
                    app.SavedImageSource = app.SavedImage();    % Process image on saved settings
                    app.PreviewApp = PreviewSavedSetting(app, app.SavedImageSource);    % Open a new window with the image of saved settings
                else
                    % Do nothing
                end
            catch
                % Do nothing
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.PreviewApp)
            delete(app)           
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            try
                currentImage = cartoonifyFilter(app.GrayScaleImage, app.Hist_StretchLower, app.Hist_StretchUpper, app.Edge_NoiseReduceFilter,...
                    app.Edge_NoiseLevel, app.Edge_DetectionMethod, app.Edge_DetectionThreshold, app.ModE_DilationLevel, app.Fill_NoiseReduceFilter,...
                    app.Fill_NoiseLevel, app.Fill_NumOfShades);
                imshow(currentImage);
                imsave
                close all;
            catch
                % Do nothing
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveCurrentSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveCurrentSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentSettingsButtonPushed, true);
            app.SaveCurrentSettingsButton.Text = 'Save Current Settings';
            app.PreviewSavedSettingsButton = uibutton(app.UIFigure, 'push');
            app.PreviewSavedSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewSavedSettingsButtonPushed, true);
            app.PreviewSavedSettingsButton.Text = 'Preview Saved Settings';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.QuantizationPanel = uipanel(app.UIFigure);
            app.QuantizationPanel.Title = 'Quantization';
            app.NumberofGrayShadesSliderLabel = uilabel(app.QuantizationPanel);
            app.NumberofGrayShadesSliderLabel.Text = 'Number of Gray Shades';
            app.NumberofGrayShadesSlider = uislider(app.QuantizationPanel);
            app.NumberofGrayShadesSlider.Limits = [1 50];
            app.NumberofGrayShadesSlider.ValueChangingFcn = createCallbackFcn(app, @NumberofGrayShadesSliderValueChanging, true);
            app.NumberofGrayShadesSlider.Value = 1;
            app.NoiseLevelFillSliderLabel = uilabel(app.QuantizationPanel);
            app.NoiseLevelFillSliderLabel.Text = ' Noise Level (Fill)';
            app.NoiseLevelFillSlider = uislider(app.QuantizationPanel);
            app.NoiseLevelFillSlider.ValueChangingFcn = createCallbackFcn(app, @NoiseLevelFillSliderValueChanging, true);
            app.NoiseReductionFilterFillDropDownLabel = uilabel(app.QuantizationPanel);
            app.NoiseReductionFilterFillDropDownLabel.Text = 'Noise Reduction Filter (Fill)';
            app.NoiseReductionFilterFillDropDown = uidropdown(app.QuantizationPanel);
            app.NoiseReductionFilterFillDropDown.Items = {'None', 'Average', 'Median'};
            app.NoiseReductionFilterFillDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseReductionFilterFillDropDownValueChanged, true);
            app.NoiseReductionFilterFillDropDown.Value = 'None';
            app.EdgeDetectionPanel = uipanel(app.UIFigure);
            app.EdgeDetectionPanel.Title = 'Edge Detection';
            app.NoiseLevelEdgeSliderLabel = uilabel(app.EdgeDetectionPanel);
            app.NoiseLevelEdgeSliderLabel.Text = 'Noise Level (Edge)';
            app.NoiseLevelEdgeSlider = uislider(app.EdgeDetectionPanel);
            app.NoiseLevelEdgeSlider.ValueChangingFcn = createCallbackFcn(app, @NoiseLevelEdgeSliderValueChanging, true);
            app.NoiseLevelEdgeSlider.Value = 1;
            app.EdgeDetectionMethodDropDownLabel = uilabel(app.EdgeDetectionPanel);
            app.EdgeDetectionMethodDropDownLabel.Text = 'Edge Detection Method';
            app.EdgeDetectionMethodDropDown = uidropdown(app.EdgeDetectionPanel);
            app.EdgeDetectionMethodDropDown.Items = {'None', 'Laplacian', 'LOG', 'Canny'};
            app.EdgeDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionMethodDropDownValueChanged, true);
            app.EdgeDetectionMethodDropDown.Value = 'None';
            app.ThresholdSliderLabel = uilabel(app.EdgeDetectionPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.EdgeDetectionPanel);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.NoiseReductionFilterEdgeDropDownLabel = uilabel(app.EdgeDetectionPanel);
            app.NoiseReductionFilterEdgeDropDownLabel.Text = 'Noise Reduction Filter (Edge)';
            app.NoiseReductionFilterEdgeDropDown = uidropdown(app.EdgeDetectionPanel);
            app.NoiseReductionFilterEdgeDropDown.Items = {'None', 'Average', 'Median'};
            app.NoiseReductionFilterEdgeDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseReductionFilterEdgeDropDownValueChanged, true);
            app.NoiseReductionFilterEdgeDropDown.Value = 'None';
            app.EdgeModificationPanel = uipanel(app.UIFigure);
            app.EdgeModificationPanel.Title = 'Edge Modification';
            app.BoldenEdgesSliderLabel = uilabel(app.EdgeModificationPanel);
            app.BoldenEdgesSliderLabel.Text = 'Bolden Edges';
            app.BoldenEdgesSlider = uislider(app.EdgeModificationPanel);
            app.BoldenEdgesSlider.Limits = [0 10];
            app.BoldenEdgesSlider.ValueChangingFcn = createCallbackFcn(app, @BoldenEdgesSliderValueChanging, true);
            app.ConstrastPanel = uipanel(app.UIFigure);
            app.ConstrastPanel.Title = 'Constrast';
            app.LowerBoundSliderLabel = uilabel(app.ConstrastPanel);
            app.LowerBoundSliderLabel.Text = 'Lower Bound';
            app.LowerBoundSlider = uislider(app.ConstrastPanel);
            app.LowerBoundSlider.Limits = [0 1];
            app.LowerBoundSlider.ValueChangedFcn = createCallbackFcn(app, @LowerBoundSliderValueChanged, true);
            app.LowerBoundSlider.ValueChangingFcn = createCallbackFcn(app, @LowerBoundSliderValueChanging, true);
            app.UpperBoundSliderLabel = uilabel(app.ConstrastPanel);
            app.UpperBoundSliderLabel.Text = 'Upper Bound';
            app.UpperBoundSlider = uislider(app.ConstrastPanel);
            app.UpperBoundSlider.Limits = [0 1];
            app.UpperBoundSlider.ValueChangedFcn = createCallbackFcn(app, @UpperBoundSliderValueChanged, true);
            app.UpperBoundSlider.ValueChangingFcn = createCallbackFcn(app, @UpperBoundSliderValueChanging, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Note: Please check your "Number of Gray Shades", Bolden Edges",'; '         and Contrast sliders if the image has turned black or white!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RealLifeComicDesignGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end